classdef RobotControlApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        SendButton               matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        SolveButton              matlab.ui.control.Button
        zEditField               matlab.ui.control.NumericEditField
        zEditFieldLabel          matlab.ui.control.Label
        yEditField               matlab.ui.control.NumericEditField
        yEditFieldLabel          matlab.ui.control.Label
        xEditField               matlab.ui.control.NumericEditField
        xEditFieldLabel          matlab.ui.control.Label
        CenterPanel              matlab.ui.container.Panel
        DisconnectButton         matlab.ui.control.Button
        ConnectionLabel          matlab.ui.control.Label
        SerialPortDropDown       matlab.ui.control.DropDown
        SerialPortDropDownLabel  matlab.ui.control.Label
        ConnectButton            matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        RightPanel               matlab.ui.container.Panel
        StartButton              matlab.ui.control.Button
        ReHomeButton             matlab.ui.control.Button
        TabGroupJointJogCartMov  matlab.ui.container.TabGroup
        JointJogTab              matlab.ui.container.Tab
        ButtonGroupJogJointdeg   matlab.ui.container.ButtonGroup
        JogJoint30deg            matlab.ui.control.ToggleButton
        JogJoint15deg            matlab.ui.control.ToggleButton
        JogJoint5deg             matlab.ui.control.ToggleButton
        ZLabelnegJogJoint        matlab.ui.control.Label
        ZLabelposJogJoint        matlab.ui.control.Label
        JogJointCW               matlab.ui.control.Button
        JogJointCCW              matlab.ui.control.Button
        ButtonGroupJogJoint      matlab.ui.container.ButtonGroup
        J6Button                 matlab.ui.control.ToggleButton
        J5Button                 matlab.ui.control.ToggleButton
        J4Button                 matlab.ui.control.ToggleButton
        J3Button                 matlab.ui.control.ToggleButton
        J2Button                 matlab.ui.control.ToggleButton
        J1Button                 matlab.ui.control.ToggleButton
        CartMoveTab              matlab.ui.container.Tab
        ButtonGroupCartMov       matlab.ui.container.ButtonGroup
        CartMove100mm            matlab.ui.control.ToggleButton
        CartMove10mm             matlab.ui.control.ToggleButton
        CartMove1mm              matlab.ui.control.ToggleButton
        TabGroupTransOrient      matlab.ui.container.TabGroup
        TranslationTab           matlab.ui.container.Tab
        ZLabelposCartMove        matlab.ui.control.Label
        CartMoveZpos             matlab.ui.control.Button
        ZLabelnegCartMove        matlab.ui.control.Label
        CartMoveZneg             matlab.ui.control.Button
        XLabelnegCartMove        matlab.ui.control.Label
        CartMoveXneg             matlab.ui.control.Button
        XLabelposCartMove        matlab.ui.control.Label
        CartMoveXpos             matlab.ui.control.Button
        YLabelposCartMove        matlab.ui.control.Label
        CartMoveYpos             matlab.ui.control.Button
        YLabelnegCartMove        matlab.ui.control.Label
        CartMoveYneg             matlab.ui.control.Button
        OrientationTab           matlab.ui.container.Tab
        ZLabelnegCartMoveOrient  matlab.ui.control.Label
        CartMoveOrientZneg       matlab.ui.control.Button
        ZLabelposCartMoveOrient  matlab.ui.control.Label
        CartMoveOrientZpos       matlab.ui.control.Button
        XLabelnegCartMoveOrient  matlab.ui.control.Label
        CartMoveOrientXneg       matlab.ui.control.Button
        XLabelposCartMoveOrient  matlab.ui.control.Label
        CartMoveOrientXpos       matlab.ui.control.Button
        YLabelposCartMoveOrient  matlab.ui.control.Label
        CartMoveOrientYpos       matlab.ui.control.Button
        YLabelnegCartMoveOrient  matlab.ui.control.Label
        CartMoveOrientYneg       matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        currconfig = [0 0 0 0 0 0]';
        currTrans = [0 0.1998 0.3340];
        currOrient = [0 pi/2 pi/2] % ZYX
        newconfig = [];
        controller = [];
        robot = [];
    end
    
    methods (Access = private)
        
        
        function jogJoints(app, mode)
            switch mode
                case "positive"
                    sign = 1;
                case "negative"
                    sign = -1;
            end

            amount = app.ButtonGroupJogJointdeg.SelectedObject;
            switch amount
                case app.JogJoint5deg
                    amount = deg2rad(5);
                case app.JogJoint15deg
                    amount = deg2rad(15);
                case app.JogJoint30deg
                    amount = deg2rad(30);
            end

            config = app.currconfig;
            joint = app.ButtonGroupJogJoint.SelectedObject;
            switch joint
                case app.J1Button
                    config = config + [amount 0 0 0 0 0]'.*sign;
                case app.J2Button
                    config = config + [0 amount 0 0 0 0]'.*sign;
                case app.J3Button
                    config = config + [0 0 amount 0 0 0]'.*sign;
                case app.J4Button
                    config = config + [0 0 0 amount 0 0]'.*sign;
                case app.J5Button
                    config = config + [0 0 0 0 amount 0]'.*sign;
                case app.J6Button
                    config = config + [0 0 0 0 0 amount]'.*sign;
            end
            
            % CHECK JOINT BOUNDARIES!!!!!!!!!
            if isValidJointPosition(app, config)
                app.currconfig = config;
                [newTrans, newOrient] = getEEPose(app, app.currconfig);

                % Set current transform according to new joint angles
                app.currTrans = newTrans;
                app.currOrient = newOrient;
            else
                warning("Planned Joint Position out of bounds!");
            end
        end
        
        % calc inverse kinematics (with joint limits hopefully) from trans
        % vector, orientation through ZYX
        % standard pos = [262.57 0 288.75]
        function qIK = calcIK(app, trans, orient)
            ee = app.robot.BodyNames{end};

            gIK = generalizedInverseKinematics("RigidBodyTree", app.robot, ...
                'ConstraintInputs', {'position', 'orientation', 'jointbounds'});

            % constraints
            posTgt = constraintPositionTarget(ee);
            posTgt.TargetPosition = trans; % trans vector, z.B. [0 0.2 0.2]
            posTgt.Weights = 1;
            
            oriTgt = constraintOrientationTarget(ee);
            oriTgt.TargetOrientation = eul2quat(orient,"ZYX"); % ZYX rotation, .z.B. [0 pi/2 pi/2]
            oriTgt.Weights = 1;
            
            jointBounds = constraintJointBounds(app.robot);
            [config, info] = gIK(app.currconfig , posTgt, oriTgt, jointBounds);

            qIK = config;
        end
        
        function sendJointRotation(app, q, type)
            if type == "RAD"
                qDEG = rad2deg(q);
            elseif type == "DEG"
                qDEG = q;
            end

            % multiply by 100 to not loose two decimals
            qDEGe100 = qDEG .* 100;
            qInt16 = int16(qDEGe100);

            % TESTETSTETSTETSTETSTE
            %configInt16 = int16([-9000, -18000, 9000, 18000, 9000, 18000]);

            % send command type
            write(app.controller, uint8('J'), "uint8");
            % send array of angles to controller
            write(app.controller, qInt16, "int16");
        end
        
        function isValid = isValidJointPosition(app, q)
            n = numel(homeConfiguration(app.robot));
            lower = zeros(n,1);
            upper = zeros(n,1);

            for i = 1:n
                joint = app.robot.Bodies{i+1}.Joint;
                if strcmp(joint.Type, 'revolute') || strcmp(joint.Type, 'prismatic')
                    limits = joint.PositionLimits;
                    lower(i) = limits(1);
                    upper(i) = limits(2);
                else
                    lower(i) = -inf;
                    upper(i) = inf;
                end
            end
                        
            isValid = all(q >= lower) && all(q <= upper);
        end

        
        function q = cartMove(app, axis, mode)

            switch mode
                case "positive"
                    sign = 1;
                case "negative"
                    sign = -1;
                otherwise
                    error("Invalid Direction! Choose positive or negative!");
            end

            switch app.ButtonGroupCartMov.SelectedObject
                case app.CartMove100mm
                    stepTrans = 0.1;
                    stepRotDEG = 45;
                case app.CartMove10mm
                    stepTrans = 0.01;
                    stepRotDEG = 15;
                case app.CartMove1mm
                    stepTrans = 0.001;
                    stepRotDEG = 5;
            end
            stepRot = deg2rad(stepRotDEG);

            newTrans = app.currTrans;
            newOrient = app.currOrient;
            
            if app.TabGroupTransOrient.SelectedTab == app.TranslationTab
                % Translation Mode
                switch axis
                    case "X" % ZYX rotation
                        newTrans = app.currTrans + sign * [0 0 stepTrans];
                    case "Y"
                        newTrans = app.currTrans + sign * [0 stepTrans 0];
                    case "Z"
                        newTrans = app.currTrans + sign * [stepTrans 0 0];
                    otherwise
                        error("Invalid Axis! Choose X, Y or Z!");
                end
            elseif app.TabGroupTransOrient.SelectedTab == app.OrientationTab
                % Rotation Mode
                switch axis
                    case "X"
                        newOrient = app.currOrient + sign * [stepRot 0 0];
                    case "Y"
                        newOrient = app.currOrient + sign * [0 stepRot 0];
                    case "Z"
                        newOrient = app.currOrient + sign * [0 0 stepRot];
                    otherwise
                        error("Invalid Axis! Choose X, Y or Z!");
                end
            else
                error("Invalid tab selection!");
            end

            q = calcIK(app, newTrans, newOrient);
        end
        
        function [position, orientation] = getEEPose(app, q)
            T = getTransform(app.robot, q, app.robot.BodyNames{end});

            position = tform2trvec(T); % [X Y Z]
            orientation = tform2eul(T); % [Z Y X]
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.robot = importrobot("PAROL6.urdf", 'DataFormat','column');
            plotRigidBodyTreeFrames(app.robot, app.currconfig, app.UIAxes, 0.08);
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)

            targetPos = [app.xEditField.Value;
                         app.yEditField.Value;
                         app.zEditField.Value];

            targetRot = [0 pi/2 pi/2];

            qIK = calcIK(app, targetPos, targetRot);            
            app.currconfig = qIK;

            cla(app.UIAxes);
            plotRigidBodyTreeFrames(app.robot, app.currconfig, app.UIAxes, 0.08);

            if ~isempty(app.controller)
                app.SendButton.Enable = "on";
            end

            qIKDEG = rad2deg(qIK);
            app.UITable.Data = {'J1', qIK(1), qIKDEG(1)
                                'J2', qIK(2), qIKDEG(2)
                                'J3', qIK(3), qIKDEG(3)
                                'J4', qIK(4), qIKDEG(4)
                                'J5', qIK(5), qIKDEG(5)
                                'J6', qIK(6), qIKDEG(6)};

        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)

            sendJointRotation(app, app.currconfig, "RAD");
            [newTrans, newOrient] = getEEPose(app, app.currconfig);

            % Set current transform according to new joint angles
            app.currTrans = newTrans;
            app.currOrient = newOrient;
        
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if isempty(app.controller)
                try
                    port = app.SerialPortDropDown.Value;
                    app.controller = serialport(port, 9600);
                    pause(2);
                    app.ConnectionLabel.Text = "Connected!";

                    app.ConnectButton.Enable = "off";
                    app.DisconnectButton.Enable = "on";
                    app.JogJointCCW.Enable = "on";
                    app.JogJointCW.Enable = "on";

                    if ~isempty(app.newconfig)
                        app.SendButton.Enable = "on";
                    end
                catch
                    app.ConnectionLabel.Text = "Unable to connect!";
                    app.controller = [];
                end
            else
                app.ConnectionLabel.Text = "Already connected!";
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            if ~isempty(app.controller)
                app.controller = [];
                app.DisconnectButton.Enable = "off";
                app.ConnectButton.Enable = "on";
                app.SendButton.Enable = "off";
                app.JogJointCCW.Enable = "off";
                app.JogJointCW.Enable = "off";
                app.ConnectionLabel.Text = "Not Connected!";
            end
        end

        % Button pushed function: JogJointCW
        function JogJointCWButtonPushed(app, event)
            jogJoints(app, "negative");
            sendJointRotation(app, app.currconfig, "RAD");
        end

        % Button pushed function: JogJointCCW
        function JogJointCCWButtonPushed(app, event)
            jogJoints(app, "positive");
            sendJointRotation(app, app.currconfig, "RAD");
        end

        % Button pushed function: CartMoveXneg
        function CartMoveXnegButtonPushed(app, event)
            qIK = cartMove(app, "X", "negative");
            app.currconfig = qIK;
            sendJointRotation(app, app.currconfig, "RAD");

            [newTrans, newOrient] = getEEPose(app, app.currconfig);
            % Set current transform according to new joint angles
            app.currTrans = newTrans;
            app.currOrient = newOrient;
        end

        % Button pushed function: CartMoveXpos
        function CartMoveXposButtonPushed(app, event)
            
        end

        % Button pushed function: CartMoveYneg
        function CartMoveYnegButtonPushed(app, event)
            
        end

        % Button pushed function: CartMoveYpos
        function CartMoveYposButtonPushed(app, event)
            
        end

        % Button pushed function: CartMoveZneg
        function CartMoveZnegButtonPushed(app, event)
            
        end

        % Button pushed function: CartMoveZpos
        function CartMoveZposButtonPushed(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.xEditFieldLabel = uilabel(app.LeftPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.LeftPanel, 'numeric');
            app.yEditFieldLabel = uilabel(app.LeftPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.LeftPanel, 'numeric');
            app.zEditFieldLabel = uilabel(app.LeftPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SolveButton = uibutton(app.LeftPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Joint'; 'rad'; 'deg'};
            app.UITable.RowName = {};
            app.SendButton = uibutton(app.LeftPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Enable = 'off';
            app.SendButton.Text = 'Send';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'RobotView')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ConnectButton = uibutton(app.CenterPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.SerialPortDropDownLabel = uilabel(app.CenterPanel);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.CenterPanel);
            app.SerialPortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7'};
            app.SerialPortDropDown.ItemsData = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7'};
            app.SerialPortDropDown.Value = 'COM1';
            app.ConnectionLabel = uilabel(app.CenterPanel);
            app.ConnectionLabel.Text = 'Not Connected!';
            app.DisconnectButton = uibutton(app.CenterPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroupJointJogCartMov = uitabgroup(app.RightPanel);
            app.JointJogTab = uitab(app.TabGroupJointJogCartMov);
            app.JointJogTab.Title = 'Joint Jog';
            app.ButtonGroupJogJoint = uibuttongroup(app.JointJogTab);
            app.J1Button = uitogglebutton(app.ButtonGroupJogJoint);
            app.J1Button.Text = 'J1';
            app.J1Button.Value = true;
            app.J2Button = uitogglebutton(app.ButtonGroupJogJoint);
            app.J2Button.Text = 'J2';
            app.J3Button = uitogglebutton(app.ButtonGroupJogJoint);
            app.J3Button.Text = 'J3';
            app.J4Button = uitogglebutton(app.ButtonGroupJogJoint);
            app.J4Button.Text = 'J4';
            app.J5Button = uitogglebutton(app.ButtonGroupJogJoint);
            app.J5Button.Text = 'J5';
            app.J6Button = uitogglebutton(app.ButtonGroupJogJoint);
            app.J6Button.Text = 'J6';
            app.JogJointCCW = uibutton(app.JointJogTab, 'push');
            app.JogJointCCW.ButtonPushedFcn = createCallbackFcn(app, @JogJointCCWButtonPushed, true);
            app.JogJointCCW.Enable = 'off';
            app.JogJointCCW.Text = '↺';
            app.JogJointCW = uibutton(app.JointJogTab, 'push');
            app.JogJointCW.ButtonPushedFcn = createCallbackFcn(app, @JogJointCWButtonPushed, true);
            app.JogJointCW.Enable = 'off';
            app.JogJointCW.Text = '↻';
            app.ZLabelposJogJoint = uilabel(app.JointJogTab);
            app.ZLabelposJogJoint.Text = '+Z';
            app.ZLabelnegJogJoint = uilabel(app.JointJogTab);
            app.ZLabelnegJogJoint.Text = '-Z';
            app.ButtonGroupJogJointdeg = uibuttongroup(app.JointJogTab);
            app.JogJoint5deg = uitogglebutton(app.ButtonGroupJogJointdeg);
            app.JogJoint5deg.Text = '5°';
            app.JogJoint5deg.Value = true;
            app.JogJoint15deg = uitogglebutton(app.ButtonGroupJogJointdeg);
            app.JogJoint15deg.Text = '15°';
            app.JogJoint30deg = uitogglebutton(app.ButtonGroupJogJointdeg);
            app.JogJoint30deg.Text = '30°';
            app.CartMoveTab = uitab(app.TabGroupJointJogCartMov);
            app.CartMoveTab.Title = 'Cart Move';
            app.TabGroupTransOrient = uitabgroup(app.CartMoveTab);
            app.TranslationTab = uitab(app.TabGroupTransOrient);
            app.TranslationTab.Title = 'Translation';
            app.CartMoveYneg = uibutton(app.TranslationTab, 'push');
            app.CartMoveYneg.ButtonPushedFcn = createCallbackFcn(app, @CartMoveYnegButtonPushed, true);
            app.CartMoveYneg.Text = '←';
            app.YLabelnegCartMove = uilabel(app.TranslationTab);
            app.YLabelnegCartMove.Text = '-Y';
            app.CartMoveYpos = uibutton(app.TranslationTab, 'push');
            app.CartMoveYpos.ButtonPushedFcn = createCallbackFcn(app, @CartMoveYposButtonPushed, true);
            app.CartMoveYpos.Text = '→';
            app.YLabelposCartMove = uilabel(app.TranslationTab);
            app.YLabelposCartMove.Text = '+Y';
            app.CartMoveXpos = uibutton(app.TranslationTab, 'push');
            app.CartMoveXpos.ButtonPushedFcn = createCallbackFcn(app, @CartMoveXposButtonPushed, true);
            app.CartMoveXpos.Text = '↓';
            app.XLabelposCartMove = uilabel(app.TranslationTab);
            app.XLabelposCartMove.Text = '+X';
            app.CartMoveXneg = uibutton(app.TranslationTab, 'push');
            app.CartMoveXneg.ButtonPushedFcn = createCallbackFcn(app, @CartMoveXnegButtonPushed, true);
            app.CartMoveXneg.Text = '↑';
            app.XLabelnegCartMove = uilabel(app.TranslationTab);
            app.XLabelnegCartMove.Text = '-X';
            app.CartMoveZneg = uibutton(app.TranslationTab, 'push');
            app.CartMoveZneg.ButtonPushedFcn = createCallbackFcn(app, @CartMoveZnegButtonPushed, true);
            app.CartMoveZneg.Text = '↓';
            app.ZLabelnegCartMove = uilabel(app.TranslationTab);
            app.ZLabelnegCartMove.Text = '-Z';
            app.CartMoveZpos = uibutton(app.TranslationTab, 'push');
            app.CartMoveZpos.ButtonPushedFcn = createCallbackFcn(app, @CartMoveZposButtonPushed, true);
            app.CartMoveZpos.Text = '↑';
            app.ZLabelposCartMove = uilabel(app.TranslationTab);
            app.ZLabelposCartMove.Text = '+Z';
            app.OrientationTab = uitab(app.TabGroupTransOrient);
            app.OrientationTab.Title = 'Orientation';
            app.CartMoveOrientYneg = uibutton(app.OrientationTab, 'push');
            app.CartMoveOrientYneg.Text = '⤾';
            app.YLabelnegCartMoveOrient = uilabel(app.OrientationTab);
            app.YLabelnegCartMoveOrient.Text = '-Y';
            app.CartMoveOrientYpos = uibutton(app.OrientationTab, 'push');
            app.CartMoveOrientYpos.Text = '⤿';
            app.YLabelposCartMoveOrient = uilabel(app.OrientationTab);
            app.YLabelposCartMoveOrient.Text = '+Y';
            app.CartMoveOrientXpos = uibutton(app.OrientationTab, 'push');
            app.CartMoveOrientXpos.Text = '⤺';
            app.XLabelposCartMoveOrient = uilabel(app.OrientationTab);
            app.XLabelposCartMoveOrient.Text = '+X';
            app.CartMoveOrientXneg = uibutton(app.OrientationTab, 'push');
            app.CartMoveOrientXneg.Text = '⤻';
            app.XLabelnegCartMoveOrient = uilabel(app.OrientationTab);
            app.XLabelnegCartMoveOrient.Text = '-X';
            app.CartMoveOrientZpos = uibutton(app.OrientationTab, 'push');
            app.CartMoveOrientZpos.Text = '⤹';
            app.ZLabelposCartMoveOrient = uilabel(app.OrientationTab);
            app.ZLabelposCartMoveOrient.Text = '+Z';
            app.CartMoveOrientZneg = uibutton(app.OrientationTab, 'push');
            app.CartMoveOrientZneg.Text = '⤸';
            app.ZLabelnegCartMoveOrient = uilabel(app.OrientationTab);
            app.ZLabelnegCartMoveOrient.Text = '-Z';
            app.ButtonGroupCartMov = uibuttongroup(app.CartMoveTab);
            app.CartMove1mm = uitogglebutton(app.ButtonGroupCartMov);
            app.CartMove1mm.Text = '1 mm';
            app.CartMove1mm.Value = true;
            app.CartMove10mm = uitogglebutton(app.ButtonGroupCartMov);
            app.CartMove10mm.Text = '10 mm';
            app.CartMove100mm = uitogglebutton(app.ButtonGroupCartMov);
            app.CartMove100mm.Text = '100 mm';
            app.ReHomeButton = uibutton(app.RightPanel, 'push');
            app.ReHomeButton.Text = 'Re-Home';
            app.StartButton = uibutton(app.RightPanel, 'push');
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotControlApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end